classdef term2fail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        plotButton                  matlab.ui.control.Button
        timeSlider                  matlab.ui.control.Slider
        timeSliderLabel             matlab.ui.control.Label
        epochEditField              matlab.ui.control.EditField
        epochEditFieldLabel         matlab.ui.control.Label
        systemDropDown              matlab.ui.control.DropDown
        systemDropDownLabel         matlab.ui.control.Label
        HEditField                  matlab.ui.control.NumericEditField
        HEditFieldLabel             matlab.ui.control.Label
        IatEditField                matlab.ui.control.NumericEditField
        IatEditFieldLabel           matlab.ui.control.Label
        IonEditField                matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        minelevationEditField       matlab.ui.control.NumericEditField
        minelevationEditFieldLabel  matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

     
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
   
    % 'Load' 버튼 콜백 함수
    % nav 데이터를 불러오는 작업을 수행합니다.
    [filename, filepath] = uigetfile('*.mat', 'Select Nav Data File'); % .mat 파일 선택 대화상자 열기
    if filename ~= 0 % 파일이 선택되었는지 확인
        navData = load(fullfile(filepath, filename)); % .mat 파일 로드
        nav = navData.nav; % nav 데이터 가져오기
        app.navData = nav; % 앱 데이터에 nav 저장

        % 슬라이더의 범위를 설정합니다.
        initialEpoch = nav.GPS.toc; % nav 데이터의 초기 시간
        finalEpoch = getCurrentTime(); % 현재 시간을 얻는 함수를 구현해야 합니다.
        app.initialEpochTextBox.Value = initialEpoch; % 초기 시간을 텍스트 박스에 표시
        app.finalEpochTextBox.Value = finalEpoch; % 현재 시간을 텍스트 박스에 표시
        app.timeSlider.Limits = [initialEpoch, finalEpoch]; % 슬라이더 범위 설정
    
    end

        end

        % Callback function
        function SliderValueChanged(app, event)


        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
    % skyplot, groundtrack 그리는 
    satellitePosition = app.satellitePosition; % 위성 위치 데이터 가져오기
    elevationMask = app.elevationMaskTextBox.Value; % Elevation Mask 값 가져오기
    groundStation = [app.ionTextBox.Value, app.iatTextBox.Value, app.hTextBox.Value]; % Ground Station 값 가져오기

    % Skyplot 그리기
    plotSkyplot(app.skyplotAxes, satellitePosition, elevationMask);

    % Ground Track 그리기
    plotGroundTrack(app.groundTrackAxes, satellitePosition);

    % Ground Station 표시
    hold(app.groundTrackAxes, 'on');
    scatter(app.groundTrackAxes, groundStation(1), groundStation(2), 'r', 'filled');
    hold(app.groundTrackAxes, 'off');
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
            
        end

        % Value changed function: systemDropDown
        function systemDropDownValueChanged(app, event)
    % 'Satellite System' 드롭 다운 값 변경 시 콜백 함수
    selectedSystem = app.systemDropdown.Value; % 선택된 위성 시스템
    app.selectedSystem = selectedSystem; % 앱 데이터에 선택된 시스템 저장
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
            
        end

        % Value changed function: epochEditField
        function epochEditFieldValueChanged(app, event)
    % 'Initial Epoch' 및 'Final Epoch' 텍스트 박스 값 변경 시 콜백 함수
    initialEpoch = app.initialEpochTextBox.Value; % 초기 시간
    finalEpoch = app.finalEpochTextBox.Value; % 종료 시간
    app.initialEpochTextBox.Value = initialEpoch; % 초기 시간 텍스트 박스 업데이트
    app.finalEpochTextBox.Value = finalEpoch; % 종료 시간 텍스트 박스 업데이트
    app.timeSlider.Limits = [initialEpoch, finalEpoch]; % 슬라이더 범위 업데이트
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
        end

        % Value changed function: IonEditField
        function IonEditFieldValueChanged(app, event)
    % 'Ion' 텍스트 박스 값 변경 시 콜백 함수
    ion = app.ionTextBox.Value; % Ion 값
    app.ionTextBox.Value = ion; % Ion 텍스트 박스 업데이트
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
        end

        % Value changed function: IatEditField
        function IatEditFieldValueChanged(app, event)
    % 'Iat' 텍스트 박스 값 변경 시 콜백 함수
    iat = app.iatTextBox.Value; % Iat 값
    app.iatTextBox.Value = iat; % Iat 텍스트 박스 업데이트
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
        end

        % Value changed function: HEditField
        function HEditFieldValueChanged(app, event)
    % 'H' 텍스트 박스 값 변경 시 콜백 함수
    h = app.hTextBox.Value; % H 값
    app.hTextBox.Value = h; % H 텍스트 박스 업데이트
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
        end

        % Value changed function: minelevationEditField
        function minelevationEditFieldValueChanged(app, event)
    % 'Elevation Mask' 텍스트 박스 값 변경 시 콜백 함수
    elevationMask = app.elevationMaskTextBox.Value; % Elevation Mask 값
    app.elevationMaskTextBox.Value = elevationMask; % Elevation Mask 텍스트 박스 업데이트
    targetEpoch = app.timeSlider.Value; % 슬라이더 값 (목표 시간)
    updateSatellitePosition(app, targetEpoch); % 위성 위치 업데이트 함수 호출
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sky plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'ground track')
            xlabel(app.UIAxes_2, '경도')
            ylabel(app.UIAxes_2, '위도')
            zlabel(app.UIAxes_2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.minelevationEditFieldLabel = uilabel(app.UIFigure);
            app.minelevationEditFieldLabel.Text = 'min elevation';
            app.minelevationEditField = uieditfield(app.UIFigure, 'numeric');
            app.minelevationEditField.ValueChangedFcn = createCallbackFcn(app, @minelevationEditFieldValueChanged, true);
            app.minelevationEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'IonEditField';
            app.IonEditField = uieditfield(app.UIFigure, 'numeric');
            app.IonEditField.ValueChangedFcn = createCallbackFcn(app, @IonEditFieldValueChanged, true);
            app.IonEditField.Value = 127;
            app.IatEditFieldLabel = uilabel(app.UIFigure);
            app.IatEditFieldLabel.Text = 'IatEditField';
            app.IatEditField = uieditfield(app.UIFigure, 'numeric');
            app.IatEditField.ValueChangedFcn = createCallbackFcn(app, @IatEditFieldValueChanged, true);
            app.IatEditField.Value = 37;
            app.HEditFieldLabel = uilabel(app.UIFigure);
            app.HEditFieldLabel.Text = 'HEditField';
            app.HEditField = uieditfield(app.UIFigure, 'numeric');
            app.HEditField.ValueChangedFcn = createCallbackFcn(app, @HEditFieldValueChanged, true);
            app.HEditField.Value = 1;
            app.systemDropDownLabel = uilabel(app.UIFigure);
            app.systemDropDownLabel.Text = 'systemDropDown';
            app.systemDropDown = uidropdown(app.UIFigure);
            app.systemDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.systemDropDown.ValueChangedFcn = createCallbackFcn(app, @systemDropDownValueChanged, true);
            app.systemDropDown.Value = 'GPS';
            app.epochEditFieldLabel = uilabel(app.UIFigure);
            app.epochEditFieldLabel.Text = 'epoch';
            app.epochEditField = uieditfield(app.UIFigure, 'text');
            app.epochEditField.ValueChangedFcn = createCallbackFcn(app, @epochEditFieldValueChanged, true);
            app.timeSliderLabel = uilabel(app.UIFigure);
            app.timeSliderLabel.Text = 'timeSlider';
            app.timeSlider = uislider(app.UIFigure);
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = term2fail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end